classdef fileSelect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fileSelectFigure       matlab.ui.Figure
        currentSelection       matlab.ui.control.Label
        fileListLabel          matlab.ui.control.Label
        folderListLabel        matlab.ui.control.Label
        dirList                matlab.ui.control.ListBox
        fileList               matlab.ui.control.ListBox
        fileTypeDropDown       matlab.ui.control.DropDown
        FileTypeDropDownLabel  matlab.ui.control.Label
        selectButton           matlab.ui.control.Button
        cancelButton           matlab.ui.control.Button
    end


    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    %
    % fileSelect: App to select a single file of a certain type
    %
    % Usage:
    %   fileSelect                      % default: .mat files only
    %   fileSelect('txt')               % files of defined type
    %   fileSelect({'txt'})             % files of defined type
    %   fileSelect({'txt','csv','mat'}) % for multiple file types
    %
    % Created: 
    %   April 17, 2023
    %
    % Author:
    %   Stuart Fogel, Sleep Research Laboratory, University of Ottawa
    %   sfogel@uottawa.ca
    %
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    properties (Access = public)
        filepath % path for current directory in list
        filename % name of file selected
        filetype % optional input argument for file type, e.g., fileSelect('csv')
                 % for multiple file types, e.g., fileSelect({'csv','mat'})
                 % do not include as input argument for default: .mat only
        multiselect % optional input argument to enable multiselect, e.g., fileSelect('csv','multiselect','on')
                    % default = 'off'
    end
    
    properties (Access = private)
        callingApp % properties of callingApp
    end

    methods (Access = private)

        function getSubDirectories(app, filepath)
            files = dir(filepath);
            dirFlags = [files.isdir];
            subDirs = files(dirFlags);
            listOfDirs = {subDirs(3:end).name};
            delHidden = startsWith(listOfDirs, '.');
            listOfDirs(delHidden) = [];
            [~,idx] = sort(upper(listOfDirs));
            listOfDirs = listOfDirs(idx);
            app.dirList.Items = [{'..'} listOfDirs];
            focus(app.fileList);
        end

        function getFileList(app, filepath)
            files = dir(filepath);
            dirFlags = [files.isdir];
            listOfFiles = files(~dirFlags);
            listOfFiles = {listOfFiles(:).name};
            delHidden = startsWith(listOfFiles, '.');
            delType = ~endsWith(listOfFiles, app.filetype);
            delFiles = delHidden | delType;
            listOfFiles(delFiles) = [];
            [~,idx] = sort(upper(listOfFiles));
            listOfFiles = listOfFiles(idx);
            app.fileList.Items = listOfFiles;
            app.currentSelection.Text = filepath;
            focus(app.fileList);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, filepath, filename, filetype, multiselect, enable)
            app.fileSelectFigure.Theme = 'light';
            if nargin < 2
                app.callingApp = [];
            else 
                app.callingApp = callingApp;
            end
            if exist('filepath','var') % process filepath input argument
                if ischar(filepath)
                    if ~isempty(filepath)
                        app.filepath = filepath;
                    else
                        app.filepath = pwd;
                    end
                else
                    uialert(app.fileSaveFileAsFigure,'Input must be a string.','Invalid input argument','CloseFcn',@(~,~)closeFcn(app));
                    return
                end
            else
                app.filepath = pwd;
            end
            if exist('filename','var') % process filename input argument
                if ischar(filename)
                    if ~isempty(filename)
                        app.filename = filename;
                    else
                        app.filename = '';
                    end
                else
                    uialert(app.fileSaveFileAsFigure,'Input must be a string.','Invalid input argument','CloseFcn',@(~,~)closeFcn(app));
                    return
                end
            else
                app.filename = '';
            end
            if exist('filetype','var') % process filetype input argument
                if iscell(filetype)
                    app.filetype = ['.' filetype{1}];
                    filetypelist = strcat('.', filetype);
                    app.fileTypeDropDown.Items = filetypelist;
                    if length(filetypelist) == 1
                        app.fileTypeDropDown.Enable = "off";
                    end
                elseif ischar(filetype)
                    app.filetype = ['.' filetype];
                    app.fileTypeDropDown.Items = {app.filetype};
                    app.fileTypeDropDown.Enable = "off";
                else
                    uialert(app.fileSelectFigure,'Must be string or cell array of strings.','Invalid input argument','CloseFcn',@(~,~)closeFcn(app));
                    return
                end
                if exist('filetype','var') && exist('multiselect','var') && exist('enable','var') % process multiselect input argument
                    if strcmp(multiselect,'multiselect') && strcmp(enable,'on')
                        app.fileList.Multiselect = 'on';
                        app.multiselect = 'on';
                    elseif strcmp(multiselect,'multiselect') && strcmp(enable,'off')
                        app.fileList.Multiselect = 'off';
                        app.multiselect = 'off';
                    else
                        uialert(app.fileSelectFigure,'To enable multiselect use: ''multiselect'',''on''','Invalid input argument','CloseFcn',@(~,~)closeFcn(app));
                        return
                    end
                elseif exist('multiselect','var') && ~exist('enable','var')
                    uialert(app.fileSelectFigure,'Missing value pair to enable multiselct','Invalid input argument','CloseFcn',@(~,~)closeFcn(app));
                    return
                else
                    app.multiselect = 'off';
                end
            else
                app.filetype = '.mat';
                app.fileTypeDropDown.Items = {app.filetype};
                app.fileTypeDropDown.Enable = "off";
            end
            app.fileTypeDropDown.Value = app.filetype;
            getSubDirectories(app, app.filepath);
            getFileList(app, app.filepath);
        end

        % Close request function: fileSelectFigure
        function closeFcn(app, event)
            app.filename = [];
            app.filepath = [];
            f = which('getFileSelectData');
            if ~isempty(f)
                getFileSelectData(app.callingApp,app.filepath,app.filename); % send data back to calling app
            end
            delete(app);
        end

        % Double-clicked callback: dirList
        function dirListDoubleClicked(app, event)
            if strcmp(event.Source.Value,'..') && ~isempty(app.filepath)
                pathParts = strsplit(app.filepath,filesep);
                pathIdx = find(~cellfun(@isempty,pathParts));
                newPath = pathParts(pathIdx(1:end-1));
                if ispc
                    newPath = strcat(newPath, filesep);
                else
                    newPath = strcat(filesep, newPath);
                end
                app.filepath = [newPath{:}];
                if length([app.filepath]) < 80
                    app.currentSelection.Text = [app.filepath];
                else
                    app.currentSelection.Text = [app.filepath(1:40) ' ... ' app.filepath(end-40:end) filesep];
                end
            else
                app.filepath = [app.filepath filesep event.Source.Value];
                if length([app.filepath]) < 80
                    app.currentSelection.Text = [app.filepath];
                else
                    app.currentSelection.Text = [app.filepath(1:40) ' ... ' app.filepath(end-40:end) filesep];
                end
            end
            if ~isempty(app.filepath)
                getSubDirectories(app, app.filepath);
                getFileList(app, app.filepath);
                if length([app.filepath]) < 80
                    app.currentSelection.Text = [app.filepath];
                else
                    app.currentSelection.Text = [app.filepath(1:40) ' ... ' app.filepath(end-40:end) filesep];
                end
            end
        end

        % Double-clicked callback: fileList
        function fileListDoubleClicked(app, event)
            app.filename = event.Source.Value;
            f = which('getFileSelectData');
            if ~isempty(f)
                getFileSelectData(app.callingApp,app.filepath,app.filename); % send data back to calling app
            end
            delete(app);
        end

        % Value changed function: fileList
        function fileListValueChanged(app, event)
            app.filename = event.Source.Value;
        end

        % Value changed function: fileTypeDropDown
        function fileTypeDropDownValueChanged(app, event)
            app.filetype = event.Source.Value;
            getFileList(app, app.filepath);
        end

        % Button pushed function: selectButton
        function selectButtonPushed(app, event)
            if ~isempty(app.filename)
                f = which('getFileSelectData');
                if ~isempty(f)
                    getFileSelectData(app.callingApp,app.filepath,app.filename); % send data back to calling app
                end
                delete(app);
            end
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            app.filename = [];
            app.filepath = [];
            closeFcn(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fileSelectFigure = uifigure('Visible', 'off');
            app.fileSelectFigure.Name = 'Select File...';
            app.fileSelectFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.cancelButton = uibutton(app.fileSelectFigure, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Text = 'Cancel';
            app.selectButton = uibutton(app.fileSelectFigure, 'push');
            app.selectButton.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.selectButton.Text = 'Select';
            app.FileTypeDropDownLabel = uilabel(app.fileSelectFigure);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.fileTypeDropDown = uidropdown(app.fileSelectFigure);
            app.fileTypeDropDown.Items = {};
            app.fileTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @fileTypeDropDownValueChanged, true);
            app.fileTypeDropDown.Value = {};
            app.fileList = uilistbox(app.fileSelectFigure);
            app.fileList.Items = {};
            app.fileList.ValueChangedFcn = createCallbackFcn(app, @fileListValueChanged, true);
            app.fileList.DoubleClickedFcn = createCallbackFcn(app, @fileListDoubleClicked, true);
            app.fileList.Value = {};
            app.dirList = uilistbox(app.fileSelectFigure);
            app.dirList.Items = {};
            app.dirList.DoubleClickedFcn = createCallbackFcn(app, @dirListDoubleClicked, true);
            app.dirList.Value = {};
            app.folderListLabel = uilabel(app.fileSelectFigure);
            app.folderListLabel.Text = 'Folder List';
            app.fileListLabel = uilabel(app.fileSelectFigure);
            app.fileListLabel.Text = 'File List';
            app.currentSelection = uilabel(app.fileSelectFigure);
            app.currentSelection.Text = '';
            % Show the figure after all components are created
            app.fileSelectFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fileSelect(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.fileSelectFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.fileSelectFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fileSelectFigure)
        end
    end
end