classdef montageEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SleepStageLabel        matlab.ui.control.Label
        SleepStageEdit         matlab.ui.control.EditField
        EpochLabel             matlab.ui.control.Label
        EpochDurationEdit      matlab.ui.control.EditField
        LightsOffLabel         matlab.ui.control.Label
        LightsOffEdit          matlab.ui.control.EditField
        LightsOnLabel          matlab.ui.control.Label
        LightsOnEdit           matlab.ui.control.EditField
        RecordingStartLabel    matlab.ui.control.Label
        RecordingStartEdit     matlab.ui.control.EditField
        ChannelSelectionPanel  matlab.ui.container.Panel
        ChannelsToAddEdit      matlab.ui.control.EditField
        ChannelstoaddLabel     matlab.ui.control.Label
        AvailableListBox       matlab.ui.control.ListBox
        IncludedListBox        matlab.ui.control.ListBox
        IncludeButton          matlab.ui.control.Button
        RemoveButton           matlab.ui.control.Button
        MovedownButton         matlab.ui.control.Button
        MoveupButton           matlab.ui.control.Button
        ColourPicker           matlab.ui.control.ColorPicker
        ChanbnelColourLabel    matlab.ui.control.Label
        IncludedListBoxLabel   matlab.ui.control.Label
        AvailableListBoxLabel  matlab.ui.control.Label
        SaveButton             matlab.ui.control.Button
        CancelButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        ChannelColours % colour selected for each channel
        callingApp % properties of callingApp
    end
    
    properties (Access = public)
        montageData % montage settings
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, EEG)
            if nargin < 3
                app.AvailableListBox.Items = {};
                app.ChannelColours = cell(1,length(app.IncludedListBox.Items));
            else
                if ~isempty(EEG)
                    if ~isempty(EEG.chanlocs)
                        app.AvailableListBox.Items = {EEG.chanlocs.labels};
                        app.ChannelColours = cell(1,length(app.IncludedListBox.Items));
                        for nCC = 1:length(app.ChannelColours)
                            app.ChannelColours{nCC} = rgb2hex([0 0 0]);
                        end
                    else
                        app.AvailableListBox.Items = {};
                        app.ChannelColours = cell(1,length(app.IncludedListBox.Items));
                    end
                end
            end
            if nargin < 2
                app.callingApp = [];
            else 
                app.callingApp = callingApp;
            end
        end

        % Value changed function: ChannelsToAddEdit
        function ChannelsToAddChanged(app, event)
            NewChannels = strsplit(app.ChannelsToAddEdit.Value,{' ',','});
            app.ChannelsToAddEdit.Value = '';
            if ~iscell(app.AvailableListBox.Items)
                app.AvailableListBox.Items = NewChannels;
            else
                app.AvailableListBox.Items = unique([app.AvailableListBox.Items NewChannels],'stable');
            end
        end

        % Button pushed function: IncludeButton
        function IncludeButtonPushed(app, event)
            Channels2Include = app.AvailableListBox.Value;
            AvailableChannelOrder = 1:length(app.AvailableListBox.Items);
            IncludedChannels = ismember(app.AvailableListBox.Items, Channels2Include);
            IncludedChannelOrder = IncludedChannels(AvailableChannelOrder);
            Channels2Include = app.AvailableListBox.Items(IncludedChannelOrder);
            app.IncludedListBox.Items = unique([app.IncludedListBox.Items Channels2Include],'stable');
            app.IncludedListBox.Value = app.AvailableListBox.Value;
            app.AvailableListBox.Value = {};
            app.IncludedListBox.Value = {};
            NewColours = cell(1,length(Channels2Include));
            for nNC = 1:length(NewColours)
                NewColours{nNC} = rgb2hex([0 0 0]);
            end
            app.ChannelColours = [app.ChannelColours NewColours];
            for nChColour = 1:length(app.IncludedListBox.Items)
                addStyle(app.IncludedListBox, uistyle(FontColor = app.ChannelColours{nChColour}), "item", nChColour);
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            app.ChannelColours = app.ChannelColours(~ismember(app.IncludedListBox.Items, app.IncludedListBox.Value));
            app.IncludedListBox.Items = app.IncludedListBox.Items(~ismember(app.IncludedListBox.Items, app.IncludedListBox.Value));
            for nChColour = 1:length(app.IncludedListBox.Items)
                addStyle(app.IncludedListBox, uistyle(FontColor = app.ChannelColours{nChColour}), "item", nChColour);
            end
        end

        % Button pushed function: MoveupButton
        function MoveupButtonPushed(app, event)
            Channel2MoveUp = app.IncludedListBox.Value;
            for nCh = 1:length(Channel2MoveUp)
                ChannelIdx = find(ismember(app.IncludedListBox.Items, Channel2MoveUp(nCh)));
                IncludedChannelOrder = 1:length(app.IncludedListBox.Items);
                if ChannelIdx > min(IncludedChannelOrder)
                    IncludedChannelOrder(ChannelIdx) = IncludedChannelOrder(ChannelIdx)-1;
                    IncludedChannelOrder(ChannelIdx-1) = IncludedChannelOrder(ChannelIdx-1)+1;
                    app.IncludedListBox.Items = app.IncludedListBox.Items(IncludedChannelOrder);
                    app.ChannelColours = app.ChannelColours(IncludedChannelOrder);
                    for nChColour = 1:length(IncludedChannelOrder)
                        addStyle(app.IncludedListBox, uistyle(FontColor = app.ChannelColours{nChColour}), "item", nChColour);
                    end
                else
                    return
                end
            end
        end

        % Button pushed function: MovedownButton
        function MovedownButtonPushed(app, event)
            Channel2MoveDown = app.IncludedListBox.Value;
            for nCh = flip(1:length(Channel2MoveDown))
                ChannelIdx = find(ismember(app.IncludedListBox.Items, Channel2MoveDown(nCh)));
                IncludedChannelOrder = 1:length(app.IncludedListBox.Items);
                if ChannelIdx < max(IncludedChannelOrder)
                    IncludedChannelOrder(ChannelIdx) = IncludedChannelOrder(ChannelIdx)+1;
                    IncludedChannelOrder(ChannelIdx+1) = IncludedChannelOrder(ChannelIdx+1)-1;
                    app.IncludedListBox.Items = app.IncludedListBox.Items(IncludedChannelOrder);
                    app.ChannelColours = app.ChannelColours(IncludedChannelOrder);
                    for nChColour = 1:length(IncludedChannelOrder)
                        addStyle(app.IncludedListBox, uistyle(FontColor = app.ChannelColours{nChColour}), "item", nChColour);
                    end
                else
                    return
                end
            end
        end

        % Callback function: ColourPicker
        function ColourPickerChanged(app, event)
            NewColour = app.ColourPicker.Value;
            Channel = app.IncludedListBox.Value;
            for nCh = flip(1:length(Channel))
                ChannelIdx = find(ismember(app.IncludedListBox.Items, Channel(nCh)));
                app.ChannelColours{ChannelIdx} = rgb2hex(NewColour);
                addStyle(app.IncludedListBox, uistyle(FontColor = NewColour), "item", ChannelIdx);
            end
            app.ColourPicker.Value = [0 0 0];
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            UIFigureCloseRequest(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.montageData.StageNames = app.SleepStageEdit.Value;
            app.montageData.EpochDuration = app.EpochDurationEdit.Value;
            app.montageData.LightsOff = app.LightsOffEdit.Value;
            app.montageData.LightsOn = app.LightsOnEdit.Value;
            app.montageData.RecordingStart = app.RecordingStartEdit.Value;
            app.montageData.ChannelList = app.IncludedListBox.Items;
            app.montageData.ChannelColours = app.ChannelColours;
            getMontageData(app.callingApp, app.montageData); % send data back to calling app
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ChannelSelectionPanel = uipanel(app.UIFigure);
            app.ChannelSelectionPanel.Title = 'Channel selection';
            app.AvailableListBoxLabel = uilabel(app.ChannelSelectionPanel);
            app.AvailableListBoxLabel.Text = 'Available:';
            app.IncludedListBoxLabel = uilabel(app.ChannelSelectionPanel);
            app.IncludedListBoxLabel.Text = 'Included:';
            app.ChanbnelColourLabel = uilabel(app.ChannelSelectionPanel);
            app.ChanbnelColourLabel.Text = 'Channel Colour';
            app.ColourPicker = uicolorpicker(app.ChannelSelectionPanel);
            app.ColourPicker.Value = [0 0 0];
            app.ColourPicker.ValueChangedFcn = createCallbackFcn(app, @ColourPickerChanged, true);
            app.MoveupButton = uibutton(app.ChannelSelectionPanel, 'push');
            app.MoveupButton.ButtonPushedFcn = createCallbackFcn(app, @MoveupButtonPushed, true);
            app.MoveupButton.Text = 'Move up';
            app.MovedownButton = uibutton(app.ChannelSelectionPanel, 'push');
            app.MovedownButton.ButtonPushedFcn = createCallbackFcn(app, @MovedownButtonPushed, true);
            app.MovedownButton.Text = 'Move down';
            app.RemoveButton = uibutton(app.ChannelSelectionPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = '<---';
            app.IncludeButton = uibutton(app.ChannelSelectionPanel, 'push');
            app.IncludeButton.ButtonPushedFcn = createCallbackFcn(app, @IncludeButtonPushed, true);
            app.IncludeButton.Text = '--->';
            app.IncludedListBox = uilistbox(app.ChannelSelectionPanel);
            app.IncludedListBox.Items = {};
            app.IncludedListBox.Multiselect = 'on';
            app.IncludedListBox.Value = {};
            app.AvailableListBox = uilistbox(app.ChannelSelectionPanel);
            app.AvailableListBox.Items = {};
            app.AvailableListBox.Multiselect = 'on';
            app.AvailableListBox.Value = {};
            app.ChannelstoaddLabel = uilabel(app.ChannelSelectionPanel);
            app.ChannelstoaddLabel.Text = 'Channels to add:';
            app.ChannelsToAddEdit = uieditfield(app.ChannelSelectionPanel, 'text');
            app.ChannelsToAddEdit.ValueChangedFcn = createCallbackFcn(app, @ChannelsToAddChanged, true);
            app.RecordingStartEdit = uieditfield(app.UIFigure, 'text');
            app.RecordingStartEdit.Value = 'yyyy-MM-dd HH:mm:ss.SSS';
            app.RecordingStartLabel = uilabel(app.UIFigure);
            app.RecordingStartLabel.Text = 'Enter Recordings Start tag date and 24-hr time format (Default: yyyy-MM-dd HH:mm:ss.SSS):';
            app.LightsOnEdit = uieditfield(app.UIFigure, 'text');
            app.LightsOnEdit.Value = 'Lights On';
            app.LightsOnLabel = uilabel(app.UIFigure);
            app.LightsOnLabel.Text = 'Enter lights On tag (Default: Lights On):';
            app.LightsOffEdit = uieditfield(app.UIFigure, 'text');
            app.LightsOffEdit.Value = 'Lights Off';
            app.LightsOffLabel = uilabel(app.UIFigure);
            app.LightsOffLabel.Text = 'Enter Lights Off tag (Default: Lights Off):';
            app.EpochDurationEdit = uieditfield(app.UIFigure, 'text');
            app.EpochDurationEdit.Value = '30';
            app.EpochLabel = uilabel(app.UIFigure);
            app.EpochLabel.Text = 'Enter epoch duration in seconds (Default: 30):';
            app.SleepStageEdit = uieditfield(app.UIFigure, 'text');
            app.SleepStageEdit.Value = 'W N1 N2 SWS REM Unscored';
            app.SleepStageLabel = uilabel(app.UIFigure);
            app.SleepStageLabel.Text = 'Enter Sleep Stage labels (Default: W N1 N2 SWS REM Unscored):';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = montageEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end